classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Panel_3            matlab.ui.container.Panel
        TextArea           matlab.ui.control.TextArea
        Panel_5            matlab.ui.container.Panel
        Button_3           matlab.ui.control.Button
        O2EditField        matlab.ui.control.NumericEditField
        O2EditFieldLabel   matlab.ui.control.Label
        COEditField        matlab.ui.control.NumericEditField
        COEditFieldLabel   matlab.ui.control.Label
        CO2EditField       matlab.ui.control.NumericEditField
        CO2EditFieldLabel  matlab.ui.control.Label
        H2OEditField       matlab.ui.control.NumericEditField
        H2OEditFieldLabel  matlab.ui.control.Label
        EditField_7        matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        umEditField        matlab.ui.control.NumericEditField
        umEditFieldLabel   matlab.ui.control.Label
        msEditField        matlab.ui.control.NumericEditField
        msLabel            matlab.ui.control.Label
        KEditField         matlab.ui.control.NumericEditField
        KLabel             matlab.ui.control.Label
        MPaEditField       matlab.ui.control.NumericEditField
        MPaEditFieldLabel  matlab.ui.control.Label
        Panel_4            matlab.ui.container.Panel
        mmEditField        matlab.ui.control.NumericEditField
        mmEditFieldLabel   matlab.ui.control.Label
        mmsEditField       matlab.ui.control.NumericEditField
        mmsLabel           matlab.ui.control.Label
        sEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        Panel_2            matlab.ui.container.Panel
        DropDown           matlab.ui.control.DropDown
        Label_6            matlab.ui.control.Label
        EditField_6        matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        Label_5            matlab.ui.control.Label
        EditField_8        matlab.ui.control.NumericEditField
        Button_2           matlab.ui.control.Button
        gLabel             matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        EditField_5        matlab.ui.control.NumericEditField
        EditField_4        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        kmLabel            matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        EditField          matlab.ui.control.NumericEditField
        Tab_2              matlab.ui.container.Tab
        UITable            matlab.ui.control.Table
        Tab_3              matlab.ui.container.Tab
        TextArea_2         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        StrArray % 调试信息
        Table % 表格矩阵
        InterpMethod % 插值方法
    end
    
    methods (Access = private)
        
        function logRefresh_func(app,StrArrayNew)
            % 刷调试信息
            if length(app.StrArray)>= 5
                app.StrArray = {};
            end
            app.StrArray = [app.StrArray,StrArrayNew];
            app.TextArea.Value = app.StrArray;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 导入数据库
            [filename,pathname] = uigetfile({'*.xlsx';'*.xls'});
            if ~isequal(filename,0)
                app.EditField_6.Value = fullfile(pathname,filename);
                StrArrayNew = {'msg:数据库 导入完毕！'};
                logRefresh_func(app,StrArrayNew);
            else
                StrArrayNew = {'error:数据库 导入失败！'};
                logRefresh_func(app,StrArrayNew);
                return
            end
            % app.Table =  readtable(filename);
            app.Table = readtable(fullfile(pathname,filename),"VariableNamingRule", "preserve");

            app.UITable.Data = app.Table;

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            er = 0;
            try
                kongqiliang = (app.EditField.Value + 1) * app.EditField_2.Value * sind(app.EditField_3.Value + 31) * cosd(app.EditField_8.Value) * (app.EditField_5.Value + 1);
                kongqiliang_v = (app.Table.("高度km") + 1) .* app.Table.("马赫数") .* sind(app.Table.("攻角°") + 31) .* cosd(app.Table.("侧滑角°")) .* (app.Table.("过载g") + 1);    % 空气量
                ranqiliang = app.EditField_4.Value * kongqiliang;
                ranqiliang_v = app.Table.("余气系数") .* kongqiliang_v;                                          % 燃气量
                kongran = kongqiliang * ranqiliang;
                kongran_v = kongqiliang_v .* ranqiliang_v;

                app.MPaEditField.Value = interp1(kongran_v,app.Table.("压力MPa"),kongran,app.InterpMethod);
                app.KEditField.Value = interp1(kongran_v,app.Table.("温度K"),kongran,app.InterpMethod);                        % 压力和温度

                app.H2OEditField.Value = interp1(kongran_v,app.Table.("H2O质量分数"),kongran,app.InterpMethod);
                app.CO2EditField.Value = interp1(kongran_v,app.Table.("CO2质量分数"),kongran,app.InterpMethod);
                app.COEditField.Value = interp1(kongran_v,app.Table.("CO质量分数"),kongran,app.InterpMethod);
                app.O2EditField.Value = interp1(kongran_v,app.Table.("O2质量分数"),kongran,app.InterpMethod);                     % 质量分数

                guoyu = app.EditField_4.Value * (app.EditField_5.Value + 1) * kongran;
                guoyu_v = app.Table.("余气系数") .* (app.Table.("过载g") + 1) .* kongran_v;                                             % 过载和余气系数

                app.EditField_7.Value = interp1(guoyu_v,app.Table.("粒子入射角度°"),guoyu,app.InterpMethod);                         % 粒子入射角度

                wenya = app.MPaEditField.Value * app.KEditField.Value;
                wenya_v = app.Table.("压力MPa") .* app.Table.("温度K");                                             % 温度和压强

                app.msEditField.Value = interp1(wenya_v,app.Table.("气流/粒子速度m/s"),wenya,app.InterpMethod);                         % 粒子速度

                app.umEditField.Value = interp1(wenya_v,app.Table.("粒径um"),wenya,app.InterpMethod);                                                % 粒径
                
                shaoshiliang = app.KEditField.Value * app.MPaEditField.Value * ...
                    (app.H2OEditField.Value + app.CO2EditField.Value + app.COEditField.Value + app.O2EditField.Value)...
                    + sin(app.EditField_7.Value) * app.msEditField.Value ^2 * app.umEditField.Value;
                shaoshiliang_v = app.Table.("压力MPa") .* app.Table.("温度K") .* ...
                    (app.Table.("H2O质量分数") + app.Table.("CO2质量分数") + app.Table.("CO质量分数") + app.Table.("O2质量分数"))...
                    + sin(app.Table.("粒子入射角度°")) .* app.Table.("气流/粒子速度m/s") .^2 .* app.Table.("粒径um")(1,1);
                app.mmsEditField.Value = interp1(shaoshiliang_v,app.Table.("烧蚀率mm/s"),shaoshiliang,app.InterpMethod);           % 烧蚀率
                

                app.mmEditField.Value = app.mmsEditField.Value * app.sEditField.Value;                  % 烧蚀量
            catch
                StrArrayNew = {'error:查询失败！'};
                logRefresh_func(app,StrArrayNew);
                er = 1;

            end
            if isequal(er,0)
                StrArrayNew = {'msg:查询成功！'};
                logRefresh_func(app,StrArrayNew);
            else
            end

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            er = 0;
            try
                shaoshiliang = app.KEditField.Value * app.MPaEditField.Value * ...
                    (app.H2OEditField.Value + app.CO2EditField.Value + app.COEditField.Value + app.O2EditField.Value)...
                    + sin(app.EditField_7.Value) * app.msEditField.Value ^2 * app.umEditField.Value;
                shaoshiliang_v = app.Table.("压力MPa") .* app.Table.("温度K") .* ...
                    (app.Table.("H2O质量分数") + app.Table.("CO2质量分数") + app.Table.("CO质量分数") + app.Table.("O2质量分数"))...
                    + sin(app.Table.("粒子入射角度°")) .* app.Table.("气流/粒子速度m/s") .^2 .* app.Table.("粒径um")(1,1);
                app.mmsEditField.Value = interp1(shaoshiliang_v,app.Table.("烧蚀率mm/s"),shaoshiliang,app.InterpMethod);           % 烧蚀率


                app.mmEditField.Value = app.mmsEditField.Value * app.sEditField.Value;                  % 烧蚀量
            catch
                StrArrayNew = {'error:查询失败！'};
                logRefresh_func(app,StrArrayNew);
                er = 1;

            end
            if isequal(er,0)
                StrArrayNew = {'msg:查询成功！'};
                logRefresh_func(app,StrArrayNew);
            else
            end

        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'linear'
                    app.InterpMethod = 'linear';
                case 'nearest'
                    app.InterpMethod = 'nearest';
                case 'pchip'
                    app.InterpMethod = 'pchip';

            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Button down function: Tab_3
        function Tab_3ButtonDown(app, event)
            winopen 硅橡胶绝热材料烧蚀传热数据库插值计算软件说明书.pdf
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '烧蚀数据库插值计算软件';
            app.UIFigure.Icon = 'icon.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主页';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '飞行状态参数';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.kmLabel = uilabel(app.Panel);
            app.kmLabel.Text = '飞行高度（0~20km）';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '攻角（-30~30°）';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '飞行马赫数（0~5）';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '余气系数';
            app.gLabel = uilabel(app.Panel);
            app.gLabel.Text = '过载（0~20g）';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '查询';
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '侧滑角（-5~5°）';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '数据库导入';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '数据库路径';
            app.EditField_6 = uieditfield(app.Panel_2, 'text');
            app.EditField_6.Editable = 'off';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '插值方法';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'linear', 'nearest', 'pchip'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'linear';
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '烧蚀计算结果';
            app.sEditFieldLabel = uilabel(app.Panel_4);
            app.sEditFieldLabel.Text = '烧蚀时间（s）';
            app.sEditField = uieditfield(app.Panel_4, 'numeric');
            app.mmsLabel = uilabel(app.Panel_4);
            app.mmsLabel.Text = '烧蚀率（mm/s）';
            app.mmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.mmsEditField.Editable = 'off';
            app.mmEditFieldLabel = uilabel(app.Panel_4);
            app.mmEditFieldLabel.Text = '烧蚀量（mm）';
            app.mmEditField = uieditfield(app.Panel_4, 'numeric');
            app.mmEditField.Editable = 'off';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '热力载荷参数';
            app.MPaEditFieldLabel = uilabel(app.Panel_5);
            app.MPaEditFieldLabel.Text = '压力（0~2MPa）';
            app.MPaEditField = uieditfield(app.Panel_5, 'numeric');
            app.KLabel = uilabel(app.Panel_5);
            app.KLabel.Text = '温度（500~3000K）';
            app.KEditField = uieditfield(app.Panel_5, 'numeric');
            app.msLabel = uilabel(app.Panel_5);
            app.msLabel.Text = '气流/粒子速度（100~500m/s）';
            app.msEditField = uieditfield(app.Panel_5, 'numeric');
            app.umEditFieldLabel = uilabel(app.Panel_5);
            app.umEditFieldLabel.Text = '粒径（um）';
            app.umEditField = uieditfield(app.Panel_5, 'numeric');
            app.Label_7 = uilabel(app.Panel_5);
            app.Label_7.Text = '粒子入射角度（0~90°）';
            app.EditField_7 = uieditfield(app.Panel_5, 'numeric');
            app.H2OEditFieldLabel = uilabel(app.Panel_5);
            app.H2OEditFieldLabel.Text = 'H2O质量分数';
            app.H2OEditField = uieditfield(app.Panel_5, 'numeric');
            app.CO2EditFieldLabel = uilabel(app.Panel_5);
            app.CO2EditFieldLabel.Text = 'CO2质量分数';
            app.CO2EditField = uieditfield(app.Panel_5, 'numeric');
            app.COEditFieldLabel = uilabel(app.Panel_5);
            app.COEditFieldLabel.Text = 'CO质量分数';
            app.COEditField = uieditfield(app.Panel_5, 'numeric');
            app.O2EditFieldLabel = uilabel(app.Panel_5);
            app.O2EditFieldLabel.Text = 'O2质量分数';
            app.O2EditField = uieditfield(app.Panel_5, 'numeric');
            app.Button_3 = uibutton(app.Panel_5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '查询';
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '信息显示';
            app.TextArea = uitextarea(app.Panel_3);
            app.TextArea.Editable = 'off';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '数据库表格';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'序号'; '高度km'; '马赫数'; '攻角°'; '侧滑角°'; '余气系数'; '过载g'; '压力MPa'; '温度K'; '气流/粒子速度m/s'; '粒径um'; '粒子入射角°'; 'H2O质量分数'; 'CO2质量分数'; 'CO质量分数'; 'O2质量分数'; '烧蚀时间s'; '烧蚀率mm/s'; '烧蚀量mm'};
            app.UITable.RowName = {};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '帮助';
            app.Tab_3.ButtonDownFcn = createCallbackFcn(app, @Tab_3ButtonDown, true);
            app.TextArea_2 = uitextarea(app.Tab_3);
            app.TextArea_2.Value = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '                                                                         Copyright© 燃烧、热结构与内流场重点实验室'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end